classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        TabGroup              matlab.ui.container.TabGroup
        tab1                  matlab.ui.container.Tab
        UIAxes                matlab.ui.control.UIAxes
        Label                 matlab.ui.control.Label
        DropDown              matlab.ui.control.DropDown
        Slider_2Label         matlab.ui.control.Label
        Slider_2              matlab.ui.control.Slider
        Label_10              matlab.ui.control.Label
        DropDownClipChoice    matlab.ui.control.DropDown
        Button_11             matlab.ui.control.Button
        Tab                   matlab.ui.container.Tab
        UIAxes2               matlab.ui.control.UIAxes
        UIAxes2_2             matlab.ui.control.UIAxes
        DropDown_2Label       matlab.ui.control.Label
        tab2DropDown          matlab.ui.control.DropDown
        Button_6              matlab.ui.control.Button
        fsEditFieldLabel      matlab.ui.control.Label
        fsEditField           matlab.ui.control.NumericEditField
        wpEditFieldLabel      matlab.ui.control.Label
        wpEditField           matlab.ui.control.NumericEditField
        piLabel               matlab.ui.control.Label
        wsEditFieldLabel      matlab.ui.control.Label
        wsEditField           matlab.ui.control.NumericEditField
        piLabel_2             matlab.ui.control.Label
        deltapEditFieldLabel  matlab.ui.control.Label
        deltapEditField       matlab.ui.control.NumericEditField
        deltasEditFieldLabel  matlab.ui.control.Label
        deltasEditField       matlab.ui.control.NumericEditField
        hzLabel               matlab.ui.control.Label
        Button_7              matlab.ui.control.Button
        Panel_2               matlab.ui.container.Panel
        orderEditField        matlab.ui.control.EditField
        OrderLabel            matlab.ui.control.Label
        ZTab                  matlab.ui.container.Tab
        Panel_3               matlab.ui.container.Panel
        addPoleButton         matlab.ui.control.Button
        addPoleButton_2       matlab.ui.control.Button
        Button_8              matlab.ui.control.Button
        zUIAxes               matlab.ui.control.UIAxes
        zDropDown             matlab.ui.control.DropDown
        Label_4               matlab.ui.control.Label
        zEditField            matlab.ui.control.NumericEditField
        Label_5               matlab.ui.control.Label
        zPlotButton           matlab.ui.control.Button
        zUIAxesResult         matlab.ui.control.UIAxes
        zPlotButton_2         matlab.ui.control.Button
        tab2                  matlab.ui.container.Tab
        DropDownNoise         matlab.ui.control.DropDown
        Label_8               matlab.ui.control.Label
        UIAxesNoise           matlab.ui.control.UIAxes
        UIAxesNoiseDenoise    matlab.ui.control.UIAxes
        Panel_4               matlab.ui.container.Panel
        GridLayout            matlab.ui.container.GridLayout
        Button_9              matlab.ui.control.StateButton
        Button_10             matlab.ui.control.StateButton
        Label_7               matlab.ui.control.Label
        Spinner               matlab.ui.control.Spinner
        Label_9               matlab.ui.control.Label
        DropDown_2            matlab.ui.control.DropDown
        dBLabel               matlab.ui.control.Label
        DropDown_3Label       matlab.ui.control.Label
        DropDownChoice        matlab.ui.control.DropDown
        Panel                 matlab.ui.container.Panel
        dirButton             matlab.ui.control.Button
        UITable               matlab.ui.control.Table
        fileButton            matlab.ui.control.Button
        Label_6               matlab.ui.control.Label
        EditFieldSampleRate   matlab.ui.control.NumericEditField
        HzLabel               matlab.ui.control.Label
        playButton            matlab.ui.control.Button
        Label_2               matlab.ui.control.Label
        Slider                matlab.ui.control.Slider
        pauseButton           matlab.ui.control.Button
        stopButton            matlab.ui.control.Button
        Label_3               matlab.ui.control.Label
        playSourceChoice      matlab.ui.control.DropDown
    end

    
    properties (Access = private)
        path % current path
        file % current file
        orig_signal % audio signals
        signal
        fs % sampling rate 
        sig_ff % Description
        player % audio player 
        playerState % 0: stop  1:pause 2:playing 
        currentSample % audioplayer's sample 
        totalSeconds
        totalSamples
        appTimer
        filtered_signal % 滤波以后的信号
        filtered_signal_ff 
        filter
        filter_ff
        zfig % Description
        z_poles %
        z_zeros % Description
        z_filter 
        z_filter_ff 
        noise % Description
        noise_ff
        signal_noise % Description
        signal_noise_ff
        signal_denoise
        signal_denoise_ff
        original_XLim
    end
    
    methods (Access = private)
        
        function time_init(app)
            app.appTimer = timer;
            app.appTimer.StartDelay = 1.0;
            app.appTimer.Period = 0.2;
            app.appTimer.ExecutionMode = 'fixedSpacing';
            app.appTimer.TimerFcn = @(~, ~) timer_handler(app);
            start(app.appTimer);
        end
        
        function timer_handler(app)
            if app.playerState == 2
                app.currentSample = get(app.player,'CurrentSample');
%                 if app.currentSample < app.totalSampels && app.currentSample > 0
                    CurrentSecond_ = (app.currentSample/app.totalSamples * app.totalSeconds);
                    set(app.Slider,'Value',CurrentSecond_);
%                 end
            end
        end
        
        function timer_delete(app)
%             stop(app.appTimer);
            delete(app.appTimer);
        end
        
        function plotSignal(app,fig,tt,signal,title_,xlabel_,ylabel_,insideUI)
            if ~insideUI 
                figure(fig); 
                plot(tt,signal);
                title(title_);
                xlabel(xlabel_);
                ylabel(ylabel_);
            else 
                plot(fig,tt,signal);
                title(fig,title_);
                xlabel(fig,xlabel_);
                ylabel(fig,ylabel_);
            end
        end
        
        function z_plot(app,fig)
            figure(fig);
            zplane(app.z_zeros,app.z_poles);
        end
        
        function generateZFilter(app)
            L = app.zEditField.Value; 
            ww = [0:L-1]/(L) * 2*pi; 
            ww = exp(-1i * ww);
            z_ff = 1; 
            for i = 1: length(app.z_zeros)
                z_ff = z_ff .* (1-app.z_zeros(i) .* (ww)); 
            end
            for i = 1 : length(app.z_poles)
                z_ff = z_ff ./ (1-app.z_poles(i).*(ww));
            end
            app.z_filter_ff = z_ff; 
            app.z_filter = (ifft(z_ff)); 
            app.z_filter
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
             app.playerState = 0; 
             app.path = "./data/clean";
             dirOut = dir(fullfile(app.path,"*.wav"));
             dirOut = {dirOut.name};
             dirOut = dirOut';
             app.UITable.Data = dirOut;
             app.z_poles = [];
             app.z_zeros = [];
             time_init(app); 
       
        end

        % Button pushed function: dirButton
        function dirButtonPushed(app, event)
%             [file,path] = uigetfile({'*.wav';'*.m4a';'*.mp3'});
            app.path = uigetdir;
%             disp(fullfile(path,file));
            dirOut = dir(fullfile(app.path,"*.wav"));
            dirOut = {dirOut.name};
            dirOut = dirOut';
            app.UITable.Data = dirOut; 
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;
            value1 = app.DropDownClipChoice.Value;
            app.UIAxes.XLimMode='auto';
            if value1 == "原信号"
                if value == "时域"
                    tt = [0:length(app.orig_signal)-1]/app.fs; 
                    plot(app.UIAxes,tt,app.orig_signal);
                    title(app.UIAxes,"信号波形图");
                    xlabel(app.UIAxes,"time(s)");
                    ylabel(app.UIAxes,"Y");
                end
            end
            if value1 == "裁切信号"
                if value == "时域"
                    tt = [0:length(app.signal)-1]/app.fs; 
                    plot(app.UIAxes,tt,app.signal);
                    title(app.UIAxes,"信号波形图");
                    xlabel(app.UIAxes,"time(s)");
                    ylabel(app.UIAxes,"Y");
                else
                    tt = [0:length(app.sig_ff)-1] / length(app.sig_ff) * app.fs; 
                    plot(app.UIAxes,tt,abs(app.sig_ff));
                    title(app.UIAxes,"频域图");
                    xlabel(app.UIAxes,"frequency(hz)");
                    ylabel(app.UIAxes,"Amplitutde");
                end
            end
%             if value == "时域"
%                 tt = [0:length(app.signal)-1]/app.fs; 
%                 plot(app.UIAxes,tt,app.signal);
%                 title(app.UIAxes,"信号波形图");
%                 xlabel(app.UIAxes,"time(s)");
%                 ylabel(app.UIAxes,"Y");
%             else
%                 tt = [0:length(app.sig_ff)-1] / length(app.sig_ff) * app.fs; 
%                 plot(app.UIAxes,tt,abs(app.sig_ff));
%                 title(app.UIAxes,"频域图");
%                 xlabel(app.UIAxes,"frequency(hz)");
%                 ylabel(app.UIAxes,"Amplitutde");
%             end
        end

        % Button pushed function: playButton
        function playButtonPushed(app, event)
            play(app.player,app.currentSample);
            app.playerState = 2;
        end

        % Button pushed function: pauseButton
        function pauseButtonPushed(app, event)
            pause(app.player);
            app.playerState =1; 
        end

        % Button pushed function: stopButton
        function stopButtonPushed(app, event)
            if isempty(app.player)
                return; 
            end
            stop(app.player);
            app.playerState = 0;
            app.currentSample =1 ;
            app.Slider.Value = 0;
        end

        % Value changed function: playSourceChoice
        function playSourceChoiceValueChanged(app, event)
            value = app.playSourceChoice.Value;
            if value == "Original" && ~isempty(app.signal)
                app.player = audioplayer(app.signal,app.fs);
            end
            if value == "Filtered" && ~isempty(app.filtered_signal)
                   app.player = audioplayer(app.filtered_signal,app.fs); 
            end
           if value == "Denoised" && ~isempty(app.signal_denoise)
                app.player = audioplayer(app.signal_denoise,app.fs);
           end
           if value == "Noise" && ~isempty(app.signal_noise)
               app.player = audioplayer(app.signal_noise,app.fs);
               
           end
            
        end

        % Button pushed function: Button_7
        function Button_7Pushed(app, event)
            wp = app.wpEditField.Value; 
            ws = app.wsEditField.Value;
            deltap = app.deltapEditField.Value;
            deltas = app.deltasEditField.Value; 
            fs_ = app.fsEditField.Value;
            f = [wp,ws] / (2 * pi) * fs_; 
            [n,fo,ao,w] = firpmord(f,[1,0],[deltap,deltas],fs_);
            b = firpm(n,fo,ao,w); 
            app.filter = b; 
            app.filter_ff = fft(b); 
            
            % 设定 data
            app.orderEditField.Value = num2str(n); 
            
            % plotting 
            if app.tab2DropDown.Value == "时域"
                ww = 0:n; 
                plot(app.UIAxes2,ww,app.filter);
                title(app.UIAxes2,"冲击响应");
                xlabel(app.UIAxes2,"L");
                ylabel(app.UIAxes2,"Amplitude");
            end
            if app.tab2DropDown.Value =="频域"
                ww = [0:n]/n * 2 *pi; 
                plot(app.UIAxes2,ww,abs(app.filter_ff));
                title(app.UIAxes2,"频率响应");
                xlabel(app.UIAxes2,"w (rad)");
                ylabel(app.UIAxes2,"Amplitutde");
            end
        end

        % Value changed function: tab2DropDown
        function tab2DropDownValueChanged(app, event)
            value = app.tab2DropDown.Value;
            if ~isempty(app.filter)
                if value == "时域"
                    plot(app.UIAxes2,app.filter);
                    title(app.UIAxes2,"冲击响应");
                    xlabel(app.UIAxes2,"L");
                    ylabel(app.UIAxes2,"Amplitude");
                end
                if value == "频域"
                    ww = [1:length(app.filter_ff)]/length(app.filter_ff) * 2 *pi; 
                    plot(app.UIAxes2,ww,abs(app.filter_ff));
                    title(app.UIAxes2,"频率响应");
                    xlabel(app.UIAxes2,"w (rad)");
                    ylabel(app.UIAxes2,"Amplitutde");
                end   
            end
            if ~isempty(app.filtered_signal)
                if value == "时域"
                    plot(app.UIAxes2_2,app.filtered_signal);
                    title(app.UIAxes2_2,"冲击响应");
                    xlabel(app.UIAxes2_2,"L");
                    ylabel(app.UIAxes2_2,"Amplitude");
                end
                if value == "频域"
                    ww = [1:length(app.filtered_signal_ff)]/length(app.filtered_signal_ff) * 2 *pi; 
                    plot(app.UIAxes2_2,ww,abs(app.filtered_signal_ff));
                    title(app.UIAxes2_2,"频率响应");
                    xlabel(app.UIAxes2_2,"w (rad)");
                    ylabel(app.UIAxes2_2,"Amplitutde");
                end   
            end
            
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
            if isempty(app.filter) || isempty(app.signal)
                return; 
            end
            app.filtered_signal = conv(app.filter,app.signal);
            app.filtered_signal_ff = fft(app.filtered_signal);
            value = app.tab2DropDown.Value;
            if value == "时域"
                plot(app.UIAxes2_2,app.filtered_signal);
                title(app.UIAxes2_2,"冲击响应");
                xlabel(app.UIAxes2_2,"L");
                ylabel(app.UIAxes2_2,"Amplitude");
            end
            if value == "频域"
                ww = [1:length(app.filtered_signal_ff)]/length(app.filtered_signal_ff) * 2 *pi; 
                plot(app.UIAxes2_2,ww,abs(app.filtered_signal_ff));
                xlabel(app.UIAxes2_2,"w (rad)");
                ylabel(app.UIAxes2_2,"Amplitutde");
            end   
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            timer_delete(app); 

            close(app.zfig);

            delete(app);
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            indices = event.Indices;
            app.file = app.UITable.Data(indices);
            app.file = app.file{1,:};
            
            [signal_,Fs] = audioread(fullfile(app.path,app.file));
            signal_ = signal_(:,1);
            app.fs = app.EditFieldSampleRate.Value;
            app.signal = resample(signal_,app.fs,Fs);
            app.orig_signal = app.signal;
%             app.UITable.Data = app.signal; 
            % 加入 audio Player
            app.player = audioplayer(app.signal,app.fs);
            app.currentSample = 1; 
            app.totalSamples = get(app.player,"TotalSamples");
            app.totalSeconds = app.totalSamples/app.fs;
            
            % 设置 slider
%             set(app.Slider,"MajorTicks",[0:5]/5 * app.totalSeconds);
            set(app.Slider,"Limits",[0,app.totalSeconds]);
            
            % 转换到频域
            app.sig_ff = fft(app.signal); 
            
            % plotting 
            tt = [0:length(app.signal)-1]/app.fs; 
            plot(app.UIAxes,tt,app.signal);
            title(app.UIAxes,"信号波形图");
            xlabel(app.UIAxes,"time(s)");
            ylabel(app.UIAxes,"Y");
            app.original_XLim = app.UIAxes.XLim;
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            value = app.Slider.Value;
            app.currentSample = round(value / app.totalSeconds * app.totalSamples);
        end

        % Button pushed function: fileButton
        function fileButtonPushed(app, event)
            [app.file,app.path] = uigetfile({'*.wav';'*.m4a';'*.mp3'});
            [signal_,Fs] = audioread(fullfile(app.path,app.file));
            signal_ = signal_(:,1);
            app.fs = app.EditFieldSampleRate.Value;
            app.signal = resample(signal_,app.fs,Fs);
            app.orig_signal = app.signal;
%             app.UITable.Data = app.signal; 
            % 加入 audio Player
            app.player = audioplayer(app.signal,app.fs);
            app.currentSample = 1; 
            app.totalSamples = get(app.player,"TotalSamples");
            app.totalSeconds = app.totalSamples/app.fs; 
            
            % 设置 slider
%             set(app.Slider,"MajorTicks",[0:5]/5 * app.totalSeconds);
            set(app.Slider,"Limits",[0,app.totalSeconds]);
            
            % 转换到频域
            app.sig_ff = fft(app.signal); 
            
            % plotting 
            tt = [0:length(app.signal)-1]/app.fs; 
            plot(app.UIAxes,tt,app.signal);
            title(app.UIAxes,"信号波形图");
            xlabel(app.UIAxes,"time(s)");
            ylabel(app.UIAxes,"Y");
        end

        % Button pushed function: Button_8
        function Button_8Pushed(app, event)
            if ~isempty(app.zfig)
                close(app.zfig);
                app.z_poles = [];
                app.z_zeros = [];
            end
            app.zfig = figure();
            figure(app.zfig); 
            zplane(app.z_zeros,app.z_poles);
            app.generateZFilter();
        end

        % Button pushed function: addPoleButton
        function addPoleButtonPushed(app, event)
            if ~isempty(app.zfig)
                figure(app.zfig);
                [x_,y_] = ginput(1);
                if(abs(x_) < 0.05)
                    x_ = 0;
                end
                if abs(y_) < 0.01 
                   app.z_poles = [app.z_poles;x_]; 
                else
                    app.z_poles = [app.z_poles;x_-abs(y_)*1i];
                    app.z_poles = [app.z_poles;x_+abs(y_)*1i];
                end
                zplane(app.z_zeros,app.z_poles);
                app.generateZFilter();
            end

        end

        % Button pushed function: addPoleButton_2
        function addPoleButton_2Pushed(app, event)
            if ~isempty(app.zfig)
                figure(app.zfig);
                [x_,y_] = ginput(1);
                if abs(x_)<0.05
                    x_ = 0; 
                end
                if abs(y_) < 0.05 
                   app.z_zeros = [app.z_zeros;x_]; 
                else
                    app.z_zeros = [app.z_zeros;x_-abs(y_)*1i];
                    app.z_zeros = [app.z_zeros;x_+abs(y_)*1i];
                end
                zplane(app.z_zeros,app.z_poles);
                app.generateZFilter();
            end
        
        end

        % Button pushed function: zPlotButton
        function zPlotButtonPushed(app, event)
            if isempty(app.zfig)
                return;
            end
            if app.zDropDown.Value == "时域"
                tt = [1:length(app.z_filter)];
                app.plotSignal(app.zUIAxes,tt,real(app.z_filter),"时域","数据点","Amplitude",1);
            else 
                if app.zDropDown.Value ==  "频域"
                tt = [0:length(app.z_filter_ff)-1] / (length(app.z_filter_ff)-1) * 2 *pi;
                app.plotSignal(app.zUIAxes,tt,abs(fftshift(app.z_filter_ff)),"频域","数据点","Amplitude",1);
                
                end
            end
        end

        % Value changed function: zEditField
        function zEditFieldValueChanged(app, event)
            app.generateZFilter();
        end

        % Button pushed function: zPlotButton_2
        function zPlotButton_2Pushed(app, event)
            app.filtered_signal = conv(app.signal,app.z_filter);
            app.filtered_signal_ff = fft(app.filtered_signal);
            axes = app.zUIAxesResult;
            if app.zDropDown.Value == "时域"
                tt = [1:length(app.filtered_signal)];
                app.plotSignal(axes,tt,real(app.filtered_signal),"时域","数据点","Amplitude",1);
            else 
                if app.zDropDown.Value ==  "频域"
                tt = [0:length(app.filtered_signal_ff)-1] / (length(app.filtered_signal_ff)-1) * 2 *pi;
                app.plotSignal(axes,tt,abs(fftshift(app.filtered_signal_ff)),"频域","数据点","Amplitude",1);
                
                end
            end
        end

        % Value changed function: zDropDown
        function zDropDownValueChanged(app, event)
            value = app.zDropDown.Value;
            axes = app.zUIAxesResult;
            if app.zDropDown.Value == "时域"
                tt = [1:length(app.filtered_signal)];
                app.plotSignal(axes,tt,real(app.filtered_signal),"时域","数据点","Amplitude",1);
            else 
                if app.zDropDown.Value ==  "频域"
                tt = [0:length(app.filtered_signal_ff)-1] / (length(app.filtered_signal_ff)-1) * 2 *pi;
                app.plotSignal(axes,tt,abs(fftshift(app.filtered_signal_ff)),"频域","数据点","Amplitude",1);
                
                end
            end
            if app.zDropDown.Value == "时域"
                tt = [1:length(app.z_filter)];
                app.plotSignal(app.zUIAxes,tt,real(app.z_filter),"时域","数据点","Amplitude",1);
            else 
                if app.zDropDown.Value ==  "频域"
                tt = [0:length(app.z_filter_ff)-1] / (length(app.z_filter_ff)-1) * 2 *pi;
                app.plotSignal(app.zUIAxes,tt,abs(fftshift(app.z_filter_ff)),"频域","数据点","Amplitude",1);
                
                end
            end
        end

        % Value changed function: Button_9
        function Button_9ValueChanged(app, event)
            value = app.Spinner.Value;
            app.signal_noise = awgn(app.signal,value,'measured');
            app.noise = app.signal_noise - app.signal;
            app.signal_noise_ff = fft(app.signal_noise);
            app.noise_ff = fft(app.noise);
            if app.DropDownNoise.Value == "时域"
                if app.DropDownChoice.Value == "噪音"
                    tt = [1:length(app.noise)];
                    app.plotSignal(app.UIAxesNoise,tt,real(app.noise),"时域","数据点","Amplitude",1); 
                else 
                    if app.DropDownChoice.Value=="噪音信号"
                    tt = [1:length(app.signal_noise)];
                    app.plotSignal(app.UIAxesNoise,tt,real(app.signal_noise),"时域","数据点","Amplitude",1);  
                    end
                end
            else 
                if app.DropDownNoise.Value ==  "频域"
                    if app.DropDownChoice.Value == "噪音"
                        tt = [0:length(app.noise_ff)-1] / (length(app.noise_ff)-1) * 2 *pi;
                        app.plotSignal(app.UIAxesNoise,tt,abs(fftshift(app.noise_ff)),"频域","数据点","Amplitude",1);
                    else if app.DropDownChoice.Value=="噪音信号"
                        tt = [0:length(app.signal_noise_ff)-1] / (length(app.signal_noise_ff)-1) * 2 *pi;
                        app.plotSignal(app.UIAxesNoise,tt,abs(fftshift(app.signal_noise_ff)),"频域","数据点","Amplitude",1);
                    end
                    end                    
                end
            end
        end

        % Value changed function: DropDownChoice
        function DropDownChoiceValueChanged(app, event)
            value = app.DropDownChoice.Value;
            if app.DropDownNoise.Value == "时域"
                if app.DropDownChoice.Value == "噪音"
                    tt = [1:length(app.noise)];
                    app.plotSignal(app.UIAxesNoise,tt,real(app.noise),"时域","数据点","Amplitude",1); 
                else 
                    if app.DropDownChoice.Value=="噪音信号"
                    tt = [1:length(app.signal_noise)];
                    app.plotSignal(app.UIAxesNoise,tt,real(app.signal_noise),"时域","数据点","Amplitude",1);  
                    end
                end
            else 
                if app.DropDownNoise.Value ==  "频域"
                    if app.DropDownChoice.Value == "噪音"
                        tt = [0:length(app.noise_ff)-1] / (length(app.noise_ff)-1) * 2 *pi;
                        app.plotSignal(app.UIAxesNoise,tt,abs(fftshift(app.noise_ff)),"频域","数据点","Amplitude",1);
                    else 
                        if app.DropDownChoice.Value=="噪音信号"
                        tt = [0:length(app.signal_noise_ff)-1] / (length(app.signal_noise_ff)-1) * 2 *pi;
                        app.plotSignal(app.UIAxesNoise,tt,abs(fftshift(app.signal_noise_ff)),"频域","数据点","Amplitude",1);
                        end
                    end                    
                end
            end
        end

        % Value changed function: DropDownNoise
        function DropDownNoiseValueChanged(app, event)
            value = app.DropDownNoise.Value;
            if app.DropDownNoise.Value == "时域"
                if app.DropDownChoice.Value == "噪音"
                    tt = [1:length(app.noise)];
                    app.plotSignal(app.UIAxesNoise,tt,real(app.noise),"时域","数据点","Amplitude",1); 
                else 
                    if app.DropDownChoice.Value=="噪音信号"
                    tt = [1:length(app.signal_noise)];
                    app.plotSignal(app.UIAxesNoise,tt,real(app.signal_noise),"时域","数据点","Amplitude",1);  
                    end
                end
            else 
                if app.DropDownNoise.Value ==  "频域"
                    if app.DropDownChoice.Value == "噪音"
                        tt = [0:length(app.noise_ff)-1] / (length(app.noise_ff)-1) * 2 *pi;
                        app.plotSignal(app.UIAxesNoise,tt,abs(fftshift(app.noise_ff)),"频域","数据点","Amplitude",1);
                    else 
                        if app.DropDownChoice.Value=="噪音信号"
                        tt = [0:length(app.signal_noise_ff)-1] / (length(app.signal_noise_ff)-1) * 2 *pi;
                        app.plotSignal(app.UIAxesNoise,tt,abs(fftshift(app.signal_noise_ff)),"频域","数据点","Amplitude",1);
                        end
                    end                    
                end
            end
            % 绘制第二张图
            axes = app.UIAxesNoiseDenoise; 
            if app.DropDownNoise.Value == "时域"
                tt = [1:length(app.signal_denoise)];
                app.plotSignal(axes,tt,real(app.signal_denoise),"时域","数据点","Amplitude",1);
            else 
                if app.DropDownNoise.Value ==  "频域"
                tt = [0:length(app.signal_denoise_ff)-1] / (length(app.signal_denoise_ff)-1) * 2 *pi;
                app.plotSignal(axes,tt,abs(fftshift(app.signal_denoise_ff)),"频域","数据点","Amplitude",1);
                
                end
            end
        end

        % Value changed function: DropDown_2
        function DropDown_2ValueChanged(app, event)
            value = app.DropDown_2.Value;
            
        end

        % Value changed function: Button_10
        function Button_10ValueChanged(app, event)
            value = app.DropDown_2.Value;
            if value == "SpecSub"
                app.signal_denoise = SpecSub(app.signal_noise,app.noise);
            end
            if value == "Wiener"
                app.signal_denoise = Wiener(app.signal_noise,app.noise);
            end
            if value == "MMSE"
                app.signal_denoise = MMSE(app.signal_noise,app.noise,app.fs,0);
            end
            app.signal_denoise_ff = fft(app.signal_denoise); 
            axes = app.UIAxesNoiseDenoise; 
            if app.DropDownNoise.Value == "时域"
                tt = [1:length(app.signal_denoise)];
                app.plotSignal(axes,tt,real(app.signal_denoise),"时域","数据点","Amplitude",1);
            else 
                if app.DropDownNoise.Value ==  "频域"
                tt = [0:length(app.signal_denoise_ff)-1] / (length(app.signal_denoise_ff)-1) * 2 *pi;
                app.plotSignal(axes,tt,abs(fftshift(app.signal_denoise_ff)),"频域","数据点","Amplitude",1);
                
                end
            end
        end

        % Value changed function: Slider_2
        function Slider_2ValueChanged(app, event)
%             value = app.Slider_2.Value;
%             ratio = (11-value)/ 10;
%             app.UIAxes.XLim = [app.original_XLim(1),app.original_XLim(1) + (app.original_XLim(2)-app.original_XLim(1)) * ratio];
        end

        % Value changing function: Slider_2
        function Slider_2ValueChanging(app, event)
            if app.DropDownClipChoice.Value == "原信号"
                value = event.Value;
                ratio = (11-value)/ 11;
                app.UIAxes.XLim = [app.UIAxes.XLim(1),app.UIAxes.XLim(1) + (app.original_XLim(2)-app.original_XLim(1)) * ratio];
            end
        end

        % Value changed function: DropDownClipChoice
        function DropDownClipChoiceValueChanged(app, event)
            value1 = app.DropDownClipChoice.Value;
            value = app.DropDown.Value;
            app.UIAxes.XLimMode='auto';
            if value1 == "原信号"
                if value == "时域"
                    tt = [0:length(app.orig_signal)-1]/app.fs; 
                    plot(app.UIAxes,tt,app.orig_signal);
                    title(app.UIAxes,"信号波形图");
                    xlabel(app.UIAxes,"time(s)");
                    ylabel(app.UIAxes,"Y");
                end
            end
            if value1 == "裁切信号"
                if value == "时域"
                    tt = [0:length(app.signal)-1]/app.fs; 
                    plot(app.UIAxes,tt,app.signal);
                    title(app.UIAxes,"信号波形图");
                    xlabel(app.UIAxes,"time(s)");
                    ylabel(app.UIAxes,"Y");
                else
                    tt = [0:length(app.sig_ff)-1] / length(app.sig_ff) * app.fs; 
                    plot(app.UIAxes,tt,abs(app.sig_ff));
                    title(app.UIAxes,"频域图");
                    xlabel(app.UIAxes,"frequency(hz)");
                    ylabel(app.UIAxes,"Amplitutde");
                end
            end
        end

        % Button pushed function: Button_11
        function Button_11Pushed(app, event)
            range = app.UIAxes.XLim;
            range = floor(range * app.fs);
            app.signal = app.orig_signal(range(1)+3:range(2)-3);
            app.sig_ff = fft(app.signal);
        end

        % Value changed function: Spinner
        function SpinnerValueChanged(app, event)
            value = app.Spinner.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.tab1 = uitab(app.TabGroup);
            app.tab1.Title = '时频转换';
            app.UIAxes = uiaxes(app.tab1);
            title(app.UIAxes, '信号波形图')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.Label = uilabel(app.tab1);
            app.Label.Text = '时域/频域';
            app.DropDown = uidropdown(app.tab1);
            app.DropDown.Items = {'时域', '频域', '频谱图'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = '时域';
            app.Slider_2Label = uilabel(app.tab1);
            app.Slider_2Label.Text = 'Slider';
            app.Slider_2 = uislider(app.tab1);
            app.Slider_2.Limits = [1 10];
            app.Slider_2.ValueChangedFcn = createCallbackFcn(app, @Slider_2ValueChanged, true);
            app.Slider_2.ValueChangingFcn = createCallbackFcn(app, @Slider_2ValueChanging, true);
            app.Slider_2.Value = 1;
            app.Label_10 = uilabel(app.tab1);
            app.Label_10.Text = '显示的片段';
            app.DropDownClipChoice = uidropdown(app.tab1);
            app.DropDownClipChoice.Items = {'原信号', '裁切信号'};
            app.DropDownClipChoice.ValueChangedFcn = createCallbackFcn(app, @DropDownClipChoiceValueChanged, true);
            app.DropDownClipChoice.Value = '原信号';
            app.Button_11 = uibutton(app.tab1, 'push');
            app.Button_11.ButtonPushedFcn = createCallbackFcn(app, @Button_11Pushed, true);
            app.Button_11.Text = '裁切';
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = '滤波器';
            app.UIAxes2 = uiaxes(app.Tab);
            title(app.UIAxes2, 'Filter')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.UIAxes2_2 = uiaxes(app.Tab);
            title(app.UIAxes2_2, 'Result')
            xlabel(app.UIAxes2_2, 'X')
            ylabel(app.UIAxes2_2, 'Y')
            app.DropDown_2Label = uilabel(app.Tab);
            app.DropDown_2Label.Text = '时域/频域';
            app.tab2DropDown = uidropdown(app.Tab);
            app.tab2DropDown.Items = {'时域', '频域'};
            app.tab2DropDown.ValueChangedFcn = createCallbackFcn(app, @tab2DropDownValueChanged, true);
            app.tab2DropDown.Value = '时域';
            app.Button_6 = uibutton(app.Tab, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Text = '运行';
            app.fsEditFieldLabel = uilabel(app.Tab);
            app.fsEditFieldLabel.Text = 'fs';
            app.fsEditField = uieditfield(app.Tab, 'numeric');
            app.fsEditField.Value = 8000;
            app.wpEditFieldLabel = uilabel(app.Tab);
            app.wpEditFieldLabel.Text = 'wp';
            app.wpEditField = uieditfield(app.Tab, 'numeric');
            app.wpEditField.Value = 0.68;
            app.piLabel = uilabel(app.Tab);
            app.piLabel.Text = 'pi';
            app.wsEditFieldLabel = uilabel(app.Tab);
            app.wsEditFieldLabel.Text = 'ws';
            app.wsEditField = uieditfield(app.Tab, 'numeric');
            app.wsEditField.Value = 0.72;
            app.piLabel_2 = uilabel(app.Tab);
            app.piLabel_2.Text = 'pi';
            app.deltapEditFieldLabel = uilabel(app.Tab);
            app.deltapEditFieldLabel.Text = 'deltap';
            app.deltapEditField = uieditfield(app.Tab, 'numeric');
            app.deltapEditField.Value = 0.01;
            app.deltasEditFieldLabel = uilabel(app.Tab);
            app.deltasEditFieldLabel.Text = 'deltas';
            app.deltasEditField = uieditfield(app.Tab, 'numeric');
            app.deltasEditField.Value = 0.01;
            app.hzLabel = uilabel(app.Tab);
            app.hzLabel.Text = 'hz';
            app.Button_7 = uibutton(app.Tab, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Button_7Pushed, true);
            app.Button_7.Text = '生成';
            app.Panel_2 = uipanel(app.Tab);
            app.Panel_2.Title = '滤波器';
            app.orderEditField = uieditfield(app.Panel_2, 'text');
            app.orderEditField.Editable = 'off';
            app.OrderLabel = uilabel(app.Panel_2);
            app.OrderLabel.Text = 'Order';
            app.ZTab = uitab(app.TabGroup);
            app.ZTab.Title = 'Z域';
            app.Panel_3 = uipanel(app.ZTab);
            app.Panel_3.Title = '设置';
            app.addPoleButton = uibutton(app.Panel_3, 'push');
            app.addPoleButton.ButtonPushedFcn = createCallbackFcn(app, @addPoleButtonPushed, true);
            app.addPoleButton.Icon = 'x.svg';
            app.addPoleButton.Text = '添加极点';
            app.addPoleButton_2 = uibutton(app.Panel_3, 'push');
            app.addPoleButton_2.ButtonPushedFcn = createCallbackFcn(app, @addPoleButton_2Pushed, true);
            app.addPoleButton_2.Icon = 'record.svg';
            app.addPoleButton_2.Text = '添加零点';
            app.Button_8 = uibutton(app.Panel_3, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @Button_8Pushed, true);
            app.Button_8.Text = '生成';
            app.zUIAxes = uiaxes(app.ZTab);
            title(app.zUIAxes, 'Title')
            xlabel(app.zUIAxes, 'X')
            ylabel(app.zUIAxes, 'Y')
            app.zDropDown = uidropdown(app.ZTab);
            app.zDropDown.Items = {'时域', '频域'};
            app.zDropDown.ValueChangedFcn = createCallbackFcn(app, @zDropDownValueChanged, true);
            app.zDropDown.Value = '频域';
            app.Label_4 = uilabel(app.ZTab);
            app.Label_4.Text = '滤波器长度';
            app.zEditField = uieditfield(app.ZTab, 'numeric');
            app.zEditField.ValueChangedFcn = createCallbackFcn(app, @zEditFieldValueChanged, true);
            app.zEditField.Value = 100;
            app.Label_5 = uilabel(app.ZTab);
            app.Label_5.Text = '时频转换';
            app.zPlotButton = uibutton(app.ZTab, 'push');
            app.zPlotButton.ButtonPushedFcn = createCallbackFcn(app, @zPlotButtonPushed, true);
            app.zPlotButton.Icon = 'soundwave.svg';
            app.zPlotButton.Text = '绘制';
            app.zUIAxesResult = uiaxes(app.ZTab);
            title(app.zUIAxesResult, 'Title')
            xlabel(app.zUIAxesResult, 'X')
            ylabel(app.zUIAxesResult, 'Y')
            app.zPlotButton_2 = uibutton(app.ZTab, 'push');
            app.zPlotButton_2.ButtonPushedFcn = createCallbackFcn(app, @zPlotButton_2Pushed, true);
            app.zPlotButton_2.Icon = 'play.svg';
            app.zPlotButton_2.Text = '运行';
            app.tab2 = uitab(app.TabGroup);
            app.tab2.Title = '降噪';
            app.DropDownNoise = uidropdown(app.tab2);
            app.DropDownNoise.Items = {'时域', '频域'};
            app.DropDownNoise.ValueChangedFcn = createCallbackFcn(app, @DropDownNoiseValueChanged, true);
            app.DropDownNoise.Value = '时域';
            app.Label_8 = uilabel(app.tab2);
            app.Label_8.Text = '时频转换';
            app.UIAxesNoise = uiaxes(app.tab2);
            title(app.UIAxesNoise, 'Title')
            xlabel(app.UIAxesNoise, 'X')
            ylabel(app.UIAxesNoise, 'Y')
            app.UIAxesNoiseDenoise = uiaxes(app.tab2);
            title(app.UIAxesNoiseDenoise, 'Title')
            xlabel(app.UIAxesNoiseDenoise, 'X')
            ylabel(app.UIAxesNoiseDenoise, 'Y')
            app.Panel_4 = uipanel(app.tab2);
            app.Panel_4.Title = '设置';
            app.GridLayout = uigridlayout(app.Panel_4);
            app.Button_9 = uibutton(app.GridLayout, 'state');
            app.Button_9.ValueChangedFcn = createCallbackFcn(app, @Button_9ValueChanged, true);
            app.Button_9.Text = '添加噪音';
            app.Button_10 = uibutton(app.GridLayout, 'state');
            app.Button_10.ValueChangedFcn = createCallbackFcn(app, @Button_10ValueChanged, true);
            app.Button_10.Text = '运行';
            app.Label_7 = uilabel(app.GridLayout);
            app.Label_7.Text = '噪音';
            app.Spinner = uispinner(app.GridLayout);
            app.Spinner.ValueDisplayFormat = '%.0f';
            app.Spinner.ValueChangedFcn = createCallbackFcn(app, @SpinnerValueChanged, true);
            app.Spinner.Value = 5;
            app.Label_9 = uilabel(app.GridLayout);
            app.Label_9.Text = '降噪算法';
            app.DropDown_2 = uidropdown(app.GridLayout);
            app.DropDown_2.Items = {'SpecSub', 'Wiener'};
            app.DropDown_2.ValueChangedFcn = createCallbackFcn(app, @DropDown_2ValueChanged, true);
            app.DropDown_2.Value = 'SpecSub';
            app.dBLabel = uilabel(app.GridLayout);
            app.dBLabel.Text = 'dB';
            app.DropDown_3Label = uilabel(app.tab2);
            app.DropDown_3Label.Text = '显示图像切换';
            app.DropDownChoice = uidropdown(app.tab2);
            app.DropDownChoice.Items = {'噪音信号', '噪音'};
            app.DropDownChoice.ValueChangedFcn = createCallbackFcn(app, @DropDownChoiceValueChanged, true);
            app.DropDownChoice.Value = '噪音信号';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = '数据';
            app.dirButton = uibutton(app.Panel, 'push');
            app.dirButton.ButtonPushedFcn = createCallbackFcn(app, @dirButtonPushed, true);
            app.dirButton.Icon = 'folder.svg';
            app.dirButton.Text = '打开目录';
            app.UITable = uitable(app.Panel);
            app.UITable.ColumnName = {'目录'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.fileButton = uibutton(app.Panel, 'push');
            app.fileButton.ButtonPushedFcn = createCallbackFcn(app, @fileButtonPushed, true);
            app.fileButton.Icon = 'filetype-wav.svg';
            app.fileButton.Text = '打开文件';
            app.Label_6 = uilabel(app.Panel);
            app.Label_6.Text = '采样频率';
            app.EditFieldSampleRate = uieditfield(app.Panel, 'numeric');
            app.EditFieldSampleRate.Value = 8000;
            app.HzLabel = uilabel(app.Panel);
            app.HzLabel.Text = 'Hz';
            app.playButton = uibutton(app.UIFigure, 'push');
            app.playButton.ButtonPushedFcn = createCallbackFcn(app, @playButtonPushed, true);
            app.playButton.Icon = 'play.svg';
            app.playButton.Text = '播放';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = {'播放时间'; ''};
            app.Slider = uislider(app.UIFigure);
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.pauseButton = uibutton(app.UIFigure, 'push');
            app.pauseButton.ButtonPushedFcn = createCallbackFcn(app, @pauseButtonPushed, true);
            app.pauseButton.Icon = 'pause.svg';
            app.pauseButton.Text = '暂停';
            app.stopButton = uibutton(app.UIFigure, 'push');
            app.stopButton.ButtonPushedFcn = createCallbackFcn(app, @stopButtonPushed, true);
            app.stopButton.Icon = 'stop.svg';
            app.stopButton.Text = '停止';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '播放源';
            app.playSourceChoice = uidropdown(app.UIFigure);
            app.playSourceChoice.Items = {'Original', 'Filtered', 'Denoised', 'Noise'};
            app.playSourceChoice.ValueChangedFcn = createCallbackFcn(app, @playSourceChoiceValueChanged, true);
            app.playSourceChoice.Value = 'Original';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end